classdef notasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EditField             matlab.ui.control.EditField
        EditFieldLabel        matlab.ui.control.Label
        EliminarButton        matlab.ui.control.Button
        agregar               matlab.ui.control.Button
        nombreEditField       matlab.ui.control.EditField
        nombreEditFieldLabel  matlab.ui.control.Label
        nota2EditField        matlab.ui.control.NumericEditField
        nota2EditFieldLabel   matlab.ui.control.Label
        nota1EditField        matlab.ui.control.NumericEditField
        nota1EditFieldLabel   matlab.ui.control.Label
        planilla              matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: agregar
        function agregarButtonPushed(app, event)
            app.planilla.Data = [app.planilla.Data; [{app.nombreEditField.Value},app.nota1EditField.Value,app.nota2EditField.Value,(app.nota1EditField.Value+app.nota2EditField.Value)/2]];
            disp(app.planilla.Data(:,1))
            bar(app.UIAxes,[app.planilla.Data(:,1)],[app.planilla.Data{:,4}])
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            dat=app.planilla.Data;
            nombres=[dat(:,1)];
            for i=1:length(nombres)
                if(strcmp(nombres(i),app.EditField.Value))
                    dat(i,:)=[];
                    break
                end    
            end
            app.planilla.Data=dat;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.planilla = uitable(app.UIFigure);
            app.planilla.ColumnName = {'Nombre'; 'Nota 1'; 'Nota 2'; 'Nota Final'};
            app.planilla.RowName = {};
            app.nota1EditFieldLabel = uilabel(app.UIFigure);
            app.nota1EditFieldLabel.Text = 'nota 1';
            app.nota1EditField = uieditfield(app.UIFigure, 'numeric');
            app.nota2EditFieldLabel = uilabel(app.UIFigure);
            app.nota2EditFieldLabel.Text = 'nota 2';
            app.nota2EditField = uieditfield(app.UIFigure, 'numeric');
            app.nombreEditFieldLabel = uilabel(app.UIFigure);
            app.nombreEditFieldLabel.Text = 'nombre';
            app.nombreEditField = uieditfield(app.UIFigure, 'text');
            app.agregar = uibutton(app.UIFigure, 'push');
            app.agregar.ButtonPushedFcn = createCallbackFcn(app, @agregarButtonPushed, true);
            app.agregar.Text = 'agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = notasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end