classdef progAMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProgAUIFigure             matlab.ui.Figure
        InicioMenu                matlab.ui.container.Menu
        DatosMenu                 matlab.ui.container.Menu
        GraficoMenu               matlab.ui.container.Menu
        SalirMenu                 matlab.ui.container.Menu
        EdicinMenu                matlab.ui.container.Menu
        AgregarMenu               matlab.ui.container.Menu
        ProgramacinAvanzadaLabel  matlab.ui.control.Label
        Planilla                  matlab.ui.control.Table
        GPlanilla                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        vistaAgregar % Description
    end
    
    methods (Access = public)
        
        function actualizarTabla(app,nombre,nota1,nota2)

            app.data=[app.data;[{nombre},nota1,nota2,(nota1+nota2)/2]];
            app.Planilla.Data=app.data;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Planilla.Data=[{'Juan'},3.4,2.7,(3.4+2.7)/2];
            app.data=[{'Juan'},3.4,2.7,(3.4+2.7)/2];
        end

        % Menu selected function: DatosMenu
        function DatosMenuSelected(app, event)
            app.Planilla.Visible="on";
            app.GPlanilla.Visible="off";
            app.Planilla.Data=app.data;
        end

        % Menu selected function: GraficoMenu
        function GraficoMenuSelected(app, event)
            app.Planilla.Visible="off";
            app.GPlanilla.Visible="on";
            bar(app.GPlanilla,[app.data(:,1)],[app.data{:,4}]);

        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: AgregarMenu
        function AgregarMenuSelected(app, event)
            cest=length([app.data{:,4}]);
            
            app.vistaAgregar=progAAgregar(app,cest);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProgAUIFigure = uifigure('Visible', 'off');
            app.ProgAUIFigure.Name = 'ProgA';
            app.InicioMenu = uimenu(app.ProgAUIFigure);
            app.InicioMenu.Text = 'Inicio';
            app.DatosMenu = uimenu(app.InicioMenu);
            app.DatosMenu.MenuSelectedFcn = createCallbackFcn(app, @DatosMenuSelected, true);
            app.DatosMenu.Text = 'Datos';
            app.GraficoMenu = uimenu(app.InicioMenu);
            app.GraficoMenu.MenuSelectedFcn = createCallbackFcn(app, @GraficoMenuSelected, true);
            app.GraficoMenu.Text = 'Grafico';
            app.SalirMenu = uimenu(app.InicioMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.EdicinMenu = uimenu(app.ProgAUIFigure);
            app.EdicinMenu.Text = 'Edición';
            app.AgregarMenu = uimenu(app.EdicinMenu);
            app.AgregarMenu.MenuSelectedFcn = createCallbackFcn(app, @AgregarMenuSelected, true);
            app.AgregarMenu.Text = 'Agregar';
            app.GPlanilla = uiaxes(app.ProgAUIFigure);
            title(app.GPlanilla, 'Notas Finales')
            xlabel(app.GPlanilla, 'Estudiantes')
            ylabel(app.GPlanilla, 'Nota')
            zlabel(app.GPlanilla, 'Z')
            app.GPlanilla.Visible = 'off';
            app.Planilla = uitable(app.ProgAUIFigure);
            app.Planilla.ColumnName = {'Nombre'; 'Nota 1'; 'Nota 2'; 'Nota Final'};
            app.Planilla.RowName = {};
            app.Planilla.Visible = 'off';
            app.ProgramacinAvanzadaLabel = uilabel(app.ProgAUIFigure);
            app.ProgramacinAvanzadaLabel.Text = 'Programación Avanzada';
            % Show the figure after all components are created
            app.ProgAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = progAMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProgAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProgAUIFigure)
        end
    end
end